classdef ventanaOrdenN < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        AplicarButton                 matlab.ui.control.Button
        SeleccionarordenSpinner       matlab.ui.control.Spinner
        SeleccionarordenSpinnerLabel  matlab.ui.control.Label
        MximosymnimosdeordenNLabel    matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: AplicarButton
        function AplicarButtonPushed(app, event)
            if size(app.imgPro, 3) == 3 % RGB

                imagen_gris = rgb2gray(app.imgPro);
                tamano_filtro_str = app.SeleccioneordenSpinner.Value;
                sizeFiltro = tamano_filtro_str;
                filtroN = ones(sizeFiltro) / (sizeFiltro ^ 2);
                tipoNFilter = imfilter(imagen_gris, filtroN);

            elseif islogical(app.imgPro) % Binaria

                imagen_gris = uint8(app.imgPro) * 255;
                tamano_filtro_str = app.SeleccioneordenSpinner.Value;
                sizeFiltro = tamano_filtro_str;
                filtroN = ones(sizeFiltro) / (sizeFiltro ^ 2);
                tipoNFilter = imfilter(imagen_gris, filtroN);

            else % Escala de grises
                
                imagen_gris = app.imgPro;
                tamano_filtro_str = app.SeleccioneordenSpinner.Value;
                sizeFiltro = tamano_filtro_str;
                filtroN = ones(sizeFiltro) / (sizeFiltro ^ 2);
                tipoNFilter = imfilter(imagen_gris, filtroN);

            end

            % Colocar imagen en UIAxes principal
            imshow(tipoNFilter, 'Parent', app.UIAxes2);
            app.imgDeshacer = app.imgPro;
            app.imgProcesada = tipoNFilter;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MximosymnimosdeordenNLabel = uilabel(app.UIFigure);
            app.MximosymnimosdeordenNLabel.Text = 'Máximos y mínimos de orden N';
            app.SeleccionarordenSpinnerLabel = uilabel(app.UIFigure);
            app.SeleccionarordenSpinnerLabel.Text = 'Seleccionar  orden';
            app.SeleccionarordenSpinner = uispinner(app.UIFigure);
            app.SeleccionarordenSpinner.Limits = [1 9];
            app.SeleccionarordenSpinner.Value = 1;
            app.AplicarButton = uibutton(app.UIFigure, 'push');
            app.AplicarButton.ButtonPushedFcn = createCallbackFcn(app, @AplicarButtonPushed, true);
            app.AplicarButton.Text = 'Aplicar';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ventanaOrdenN

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end